classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        EXPPanel                  matlab.ui.container.Panel
        GridLayout3               matlab.ui.container.GridLayout
        Switch                    matlab.ui.control.Switch
        PhidegLabel               matlab.ui.control.Label
        TiltdegLabel              matlab.ui.control.Label
        phiUI                     matlab.ui.control.Slider
        tiltUI                    matlab.ui.control.Slider
        voltUI                    matlab.ui.control.NumericEditField
        VoltagekVEditFieldLabel   matlab.ui.control.Label
        distUI                    matlab.ui.control.NumericEditField
        DistancecmEditFieldLabel  matlab.ui.control.Label
        OPTPanel                  matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        PixelSizecmLabel          matlab.ui.control.Label
        ScreenSizecmLabel         matlab.ui.control.Label
        ps1                       matlab.ui.control.NumericEditField
        ps2                       matlab.ui.control.NumericEditField
        ss2                       matlab.ui.control.NumericEditField
        ss1                       matlab.ui.control.NumericEditField
        clrmapUI                  matlab.ui.control.Spinner
        ColorSpinnerLabel         matlab.ui.control.Label
        TresholdLabel             matlab.ui.control.Label
        GAINLabel                 matlab.ui.control.Label
        thdUI                     matlab.ui.control.Knob
        gainUI                    matlab.ui.control.Knob
        SYSPanel                  matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        LatConstALabel            matlab.ui.control.Label
        NumLabel                  matlab.ui.control.Label
        DirectionVecLabel         matlab.ui.control.Label
        lc3UI                     matlab.ui.control.NumericEditField
        lc2UI                     matlab.ui.control.NumericEditField
        lc1UI                     matlab.ui.control.NumericEditField
        vec3UI                    matlab.ui.control.EditField
        vec2UI                    matlab.ui.control.EditField
        vec1UI                    matlab.ui.control.EditField
        num3UI                    matlab.ui.control.Spinner
        num1UI                    matlab.ui.control.Spinner
        num2UI                    matlab.ui.control.Spinner
        OutOfPlaneLabel           matlab.ui.control.Label
        Inplane2Label             matlab.ui.control.Label
        Inplane1Label             matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cons
        num
        vec

        dist
        volt
        tilt
        phi

        ps
        ss
        clrmap

        gain
        thd

        img

        SYS
        EXP
        OPT
        
        FG
        AX

    end
    
    methods (Access = private)
        
        function propertiesRefresh(app)
            app.cons = [app.lc1UI.Value,app.lc2UI.Value,app.lc3UI.Value];
            app.num = [app.num1UI.Value,app.num2UI.Value,app.num3UI.Value];
            app.vec = {str2vec(app.vec1UI.Value),...
                    str2vec(app.vec2UI.Value),str2vec(app.vec3UI.Value)};
            
            app.dist = app.distUI.Value;
            app.volt = app.voltUI.Value;
            app.tilt = app.tiltUI.Value;
            app.phi = app.phiUI.Value;

            app.ps = [app.ps1.Value,app.ps2.Value];
            app.ss = [app.ss1.Value,app.ss2.Value];
            app.clrmap = app.clrmapUI.Value;
            app.gain = app.gainUI.Value;
            app.thd = app.thdUI.Value;
        end

        function propertiesReconst(app)
            app.SYS.LatticeConstant = app.cons;
            app.SYS.UnitNum = app.num;
            app.SYS.Vectors = app.vec;

            app.EXP.Distance = app.dist;
            app.EXP.Voltage = app.volt;
            app.EXP.Tilt = app.tilt;
            app.EXP.Phi = app.phi;

            app.OPT.PixelSize = app.ps;
            app.OPT.ScreenSize = app.ss;
            app.OPT.ColorMap = app.clrmap;
            app.OPT.Gain = app.gain;
            app.OPT.Treshold = app.thd;
            app.OPT.ShowImage = true;
        end


        function axRefresh(app)
            app.propertiesRefresh();
            app.propertiesReconst();
            cla(app.AX);
            rheedSimu(app.SYS,app.EXP,app.OPT,app.AX)
        end

        function routine(app)
            app.propertiesRefresh();
            app.propertiesReconst();
            app.axRefresh();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('@Author: Quan')
            app.propertiesRefresh();
            app.AX = app.UIAxes;
            app.axRefresh();

        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)

            
        end

        % Value changed function: lc1UI
        function lc1UIValueChanged(app, event)
            app.cons(1) = app.lc1UI.Value;
            app.routine();       
        end

        % Value changed function: lc2UI
        function lc2UIValueChanged(app, event)
            app.cons(2) = app.lc2UI.Value;
            app.routine();
        end

        % Value changed function: lc3UI
        function lc3UIValueChanged(app, event)
            app.cons(3) = app.lc3UI.Value;
            app.routine();
        end

        % Value changed function: vec1UI
        function vec1UIValueChanged(app, event)
            app.vec{1} = str2vec(app.vec1UI.Value);
            app.routine();
        end

        % Value changed function: vec2UI
        function vec2UIValueChanged(app, event)
            app.vec{2} = str2vec(app.vec2UI.Value);
            app.routine();
        end

        % Value changed function: vec3UI
        function vec3UIValueChanged(app, event)
            app.vec{3} = str2vec(app.vec3UI.Value);
            app.routine();
        end

        % Value changed function: num1UI
        function num1UIValueChanged(app, event)
            app.num(1) = app.num1UI.Value;
            app.routine();
        end

        % Value changed function: num2UI
        function num2UIValueChanged(app, event)
            app.num(2) = app.num2UI.Value;
            app.routine();
        end

        % Value changed function: num3UI
        function num3UIValueChanged(app, event)
            app.num(3) = app.num3UI.Value;
            app.routine();
        end

        % Value changing function: gainUI
        function gainUIValueChanging(app, event)
            app.gain = event.Value;
            app.routine();
        end

        % Value changing function: thdUI
        function thdUIValueChanging(app, event)
            app.thd = event.Value;
            app.routine();
        end

        % Value changing function: tiltUI
        function tiltUIValueChanging(app, event)
            app.tilt = event.Value;
            app.routine();
        end

        % Value changing function: phiUI
        function phiUIValueChanging(app, event)
            app.phi = event.Value;
            app.routine();
        end

        % Value changed function: clrmapUI
        function clrmapUIValueChanged(app, event)
            app.clrmap = app.clrmapUI.Value;
            app.routine();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SYSPanel = uipanel(app.GridLayout);
            app.SYSPanel.Title = 'SYS';
            app.GridLayout2 = uigridlayout(app.SYSPanel);
            app.Inplane1Label = uilabel(app.GridLayout2);
            app.Inplane1Label.Text = 'Inplane 1';
            app.Inplane2Label = uilabel(app.GridLayout2);
            app.Inplane2Label.Text = 'Inplane 2';
            app.OutOfPlaneLabel = uilabel(app.GridLayout2);
            app.OutOfPlaneLabel.Text = 'OutOfPlane';
            app.num2UI = uispinner(app.GridLayout2);
            app.num2UI.Limits = [1 Inf];
            app.num2UI.ValueChangedFcn = createCallbackFcn(app, @num2UIValueChanged, true);
            app.num2UI.Value = 10;
            app.num1UI = uispinner(app.GridLayout2);
            app.num1UI.Limits = [1 Inf];
            app.num1UI.ValueChangedFcn = createCallbackFcn(app, @num1UIValueChanged, true);
            app.num1UI.Value = 10;
            app.num3UI = uispinner(app.GridLayout2);
            app.num3UI.Limits = [1 Inf];
            app.num3UI.ValueChangedFcn = createCallbackFcn(app, @num3UIValueChanged, true);
            app.num3UI.Value = 1;
            app.vec1UI = uieditfield(app.GridLayout2, 'text');
            app.vec1UI.ValueChangedFcn = createCallbackFcn(app, @vec1UIValueChanged, true);
            app.vec1UI.Value = '3/2,sqrt(3)/2,0';
            app.vec2UI = uieditfield(app.GridLayout2, 'text');
            app.vec2UI.ValueChangedFcn = createCallbackFcn(app, @vec2UIValueChanged, true);
            app.vec2UI.Value = '0,sqrt(3),0';
            app.vec3UI = uieditfield(app.GridLayout2, 'text');
            app.vec3UI.ValueChangedFcn = createCallbackFcn(app, @vec3UIValueChanged, true);
            app.vec3UI.Value = '1,1,1';
            app.lc1UI = uieditfield(app.GridLayout2, 'numeric');
            app.lc1UI.ValueChangedFcn = createCallbackFcn(app, @lc1UIValueChanged, true);
            app.lc1UI.Value = 3.8;
            app.lc2UI = uieditfield(app.GridLayout2, 'numeric');
            app.lc2UI.ValueChangedFcn = createCallbackFcn(app, @lc2UIValueChanged, true);
            app.lc2UI.Value = 3.8;
            app.lc3UI = uieditfield(app.GridLayout2, 'numeric');
            app.lc3UI.ValueChangedFcn = createCallbackFcn(app, @lc3UIValueChanged, true);
            app.lc3UI.Value = 1;
            app.DirectionVecLabel = uilabel(app.GridLayout2);
            app.DirectionVecLabel.Text = 'Direction Vec';
            app.NumLabel = uilabel(app.GridLayout2);
            app.NumLabel.Text = 'Num';
            app.LatConstALabel = uilabel(app.GridLayout2);
            app.LatConstALabel.Text = 'Lat Const(A)';
            app.OPTPanel = uipanel(app.GridLayout);
            app.OPTPanel.Title = 'OPT';
            app.GridLayout4 = uigridlayout(app.OPTPanel);
            app.gainUI = uiknob(app.GridLayout4, 'continuous');
            app.gainUI.Limits = [1 10];
            app.gainUI.ValueChangingFcn = createCallbackFcn(app, @gainUIValueChanging, true);
            app.gainUI.Value = 6;
            app.thdUI = uiknob(app.GridLayout4, 'continuous');
            app.thdUI.Limits = [0 1];
            app.thdUI.ValueChangingFcn = createCallbackFcn(app, @thdUIValueChanging, true);
            app.GAINLabel = uilabel(app.GridLayout4);
            app.GAINLabel.Text = 'GAIN';
            app.TresholdLabel = uilabel(app.GridLayout4);
            app.TresholdLabel.Text = 'Treshold';
            app.ColorSpinnerLabel = uilabel(app.GridLayout4);
            app.ColorSpinnerLabel.Text = 'Color';
            app.clrmapUI = uispinner(app.GridLayout4);
            app.clrmapUI.Limits = [1 200];
            app.clrmapUI.ValueChangedFcn = createCallbackFcn(app, @clrmapUIValueChanged, true);
            app.clrmapUI.Value = 37;
            app.ss1 = uieditfield(app.GridLayout4, 'numeric');
            app.ss1.Value = 6;
            app.ss2 = uieditfield(app.GridLayout4, 'numeric');
            app.ss2.Value = 6;
            app.ps2 = uieditfield(app.GridLayout4, 'numeric');
            app.ps2.Value = 0.02;
            app.ps1 = uieditfield(app.GridLayout4, 'numeric');
            app.ps1.Value = 0.02;
            app.ScreenSizecmLabel = uilabel(app.GridLayout4);
            app.ScreenSizecmLabel.Text = 'ScreenSize(cm)';
            app.PixelSizecmLabel = uilabel(app.GridLayout4);
            app.PixelSizecmLabel.Text = 'PixelSize(cm)';
            app.EXPPanel = uipanel(app.GridLayout);
            app.EXPPanel.Title = 'EXP';
            app.GridLayout3 = uigridlayout(app.EXPPanel);
            app.DistancecmEditFieldLabel = uilabel(app.GridLayout3);
            app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
            app.distUI = uieditfield(app.GridLayout3, 'numeric');
            app.distUI.Value = 20;
            app.VoltagekVEditFieldLabel = uilabel(app.GridLayout3);
            app.VoltagekVEditFieldLabel.Text = 'Voltage (kV)';
            app.voltUI = uieditfield(app.GridLayout3, 'numeric');
            app.voltUI.Value = 15;
            app.tiltUI = uislider(app.GridLayout3);
            app.tiltUI.Limits = [0 10];
            app.tiltUI.ValueChangingFcn = createCallbackFcn(app, @tiltUIValueChanging, true);
            app.tiltUI.Value = 1;
            app.phiUI = uislider(app.GridLayout3);
            app.phiUI.Limits = [0 90];
            app.phiUI.ValueChangingFcn = createCallbackFcn(app, @phiUIValueChanging, true);
            app.TiltdegLabel = uilabel(app.GridLayout3);
            app.TiltdegLabel.Text = 'Tilt (deg)';
            app.PhidegLabel = uilabel(app.GridLayout3);
            app.PhidegLabel.Text = 'Phi (deg)';
            app.Switch = uiswitch(app.GridLayout3, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end